classdef Final_Project_Pig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DesignateTurnOrderButton   matlab.ui.control.Button
        Player2WinsEditField       matlab.ui.control.NumericEditField
        Player2WinsEditFieldLabel  matlab.ui.control.Label
        Player1WinsEditField       matlab.ui.control.NumericEditField
        Player1WinsLabel           matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
        GameRulesButton            matlab.ui.control.Button
        PigLabel                   matlab.ui.control.Label
        Player2Label               matlab.ui.control.Label
        EndTurnButton_2            matlab.ui.control.Button
        RollDiceButton_2           matlab.ui.control.Button
        Player1Label               matlab.ui.control.Label
        EndTurnButton              matlab.ui.control.Button
        RollDiceButton             matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        myPlayerNum
        otherPlayerNum
        channelID
        writeKey
        readKey
        readDelay
        writeDelay 
    end
    
    methods (Access = private)
        
        function [] = TableThingSpeak(app)
            channelID = 2769762;
            userAPIKey = 'F47CZ1368FZ4O7Q1';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
    webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'DQAG7EUQCFDB6NVI';
            writeKey = '3VR96DRS9D6OMJM0';
        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           global i % set this up a global variable to use for open/close of game rules
           global score1
           global score2
           global change1
           global change2
           global channelID
           global userAPIKey
           global url
           global readKey
           global writeKey
           i = 0; % set the initial value of i
           change1 = 0;
           change2 = 0;
           app.myPlayerNum = 1;
           app.otherPlayerNum = 2;
           score1 = 0;
           score2 = 0;
           channelID = 2769762;
            userAPIKey = 'F47CZ1368FZ4O7Q1';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
    webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'DQAG7EUQCFDB6NVI';
            writeKey = '3VR96DRS9D6OMJM0';
            set(app.RollDiceButton_2, 'Enable', 'off');
            set(app.RollDiceButton, 'Enable', 'off');
            set(app.EndTurnButton, 'Enable', 'off');
            set(app.EndTurnButton_2, 'Enable', 'off');
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            global score1
            global change1
            global score2
            global change2
            global channelID
            global userAPIKey
            global url
            global readKey
            global writeKey
            set(app.RollDiceButton_2, 'Enable', 'off'); % turns off the roll dice button for player 2
            set(app.EndTurnButton_2, 'Enable', 'off'); % turns off the end turn button for player 2
            [y, Fs] = audioread(fullfile("Sounds", "dice.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            pause(0.2);
            app.Image.ImageSource = fullfile("Images", "dice_rolling.gif"); % substitutes the image with rolling dice gif
           pause(1); % rolling dice gif
            dice_roll = randi([1, 6]); % rolls a random number from 1 to 6
            switch dice_roll
                case 1
                    app.Image.ImageSource = fullfile("Images", "dice_1.png"); % rolled a 1
                case 2
                    app.Image.ImageSource = fullfile("Images", "dice_2.png"); % rolled a 2
                case 3
                    app.Image.ImageSource = fullfile("Images", "dice_3.png"); % rolled a 3
                case 4
                    app.Image.ImageSource = fullfile("Images", "dice_4.png"); % rolled a 4
                case 5
                    app.Image.ImageSource = fullfile("Images", "dice_5.png"); % rolled a 5
                case 6
                    app.Image.ImageSource = fullfile("Images", "dice_6.png"); % rolled a 6
            end

            change1 = change1 + dice_roll;
           if dice_roll == 1
               score1 = score1 - change1 + 1;
               app.UITable.Data(1) = score1;
               set(app.RollDiceButton_2, 'Enable', 'on');
               set(app.RollDiceButton, 'Enable', 'off');
               set(app.EndTurnButton, 'Enable', 'off');
               set(app.EndTurnButton_2, 'Enable', 'on');
               change1 = 0;
               [y, Fs] = audioread(fullfile("Sounds","chime.mp3")); % reads the audio file
               sound(y, Fs); % plays the sound
           else
               score1 = score1 + dice_roll;
               app.UITable.Data(1) = sum(score1);
               if score1 >= 100
                msgbox('Player 1 has won')
                app.Player1WinsEditField.Value = app.Player1WinsEditField.Value + 1;
                score1 = 0;
                change1 = 0;
                score2 = 0;
                change2 = 0;
               end
           end
        end

        % Button pushed function: GameRulesButton
        function GameRulesButtonPushed(app, event)
           global i % called the global variable
           i = i+1; % adding an iteration to count how many times the button is pressed
           set(app.TextArea, 'Visible','on'); % makes the text area visible
           if (mod(i, 2) == 0) 
                set(app.TextArea, 'Visible', 'off') % if it is divisible by 2 (open and close), it closes
           else
               set(app.TextArea, 'Visible', 'on') % else, open it
           end
        end

        % Button pushed function: RollDiceButton_2
        function RollDiceButton_2Pushed(app, event)
            global score2
            global change2
            global score1
            global change1
            set(app.RollDiceButton, 'Enable', 'off');
            set(app.EndTurnButton, 'Enable', 'off');
            [y, Fs] = audioread(fullfile("Sounds","dice.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            pause(0.2);
            app.Image.ImageSource = fullfile("Images", "dice_rolling.gif"); % substitutes the image with rolling dice gif
           pause(1); % rolling dice gif
            dice_roll = randi([1, 6]); % rolls a random number from 1 to 6
            switch dice_roll
                case 1
                    app.Image.ImageSource = fullfile("Images", "dice_1.png"); % rolled a 1
                case 2
                    app.Image.ImageSource = fullfile("Images", "dice_2.png"); % rolled a 2
                case 3
                    app.Image.ImageSource = fullfile("Images", "dice_3.png"); % rolled a 3
                case 4
                    app.Image.ImageSource = fullfile("Images", "dice_4.png"); % rolled a 4
                case 5
                    app.Image.ImageSource = fullfile("Images", "dice_5.png"); % rolled a 5
                case 6
                    app.Image.ImageSource = fullfile("Images", "dice_6.png"); % rolled a 6
            end

            change2 = change2 + dice_roll;
            if dice_roll == 1
               score2 = score2 - change2 + 1;
               app.UITable.Data(2) = score2;
               set(app.RollDiceButton_2, 'Enable', 'off');
               set(app.EndTurnButton_2, 'Enable', 'off');
               set(app.RollDiceButton, 'Enable', 'on');
               set(app.EndTurnButton, 'Enable', 'on');
               change2 = 0;
               [y, Fs] = audioread(fullfile("Sounds","chime.mp3")); % reads the audio file
               sound(y, Fs); % plays the sound
            else
                score2 = score2 + dice_roll;
                app.UITable.Data(2) = score2;
                if score2 >= 100
                msgbox('Player 2 has won')
                app.Player2WinsEditField.Value = app.Player2WinsEditField.Value + 1;
                score2 = 0;
                change2 = 0;
               end
            end
            
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            global change1
            [y, Fs] = audioread(fullfile("Sounds","chime.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
             % add data to table
                change1 = 0;
               set(app.RollDiceButton_2, 'Enable', 'on');
               set(app.RollDiceButton, 'Enable', 'off');
               set(app.EndTurnButton, 'Enable', 'off');
               set(app.EndTurnButton_2, 'Enable', 'on');
        end

        % Button pushed function: EndTurnButton_2
        function EndTurnButton_2Pushed(app, event)
            global change2
            [y, Fs] = audioread(fullfile("Sounds","chime.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            % add data to table
                change2 = 0;
               set(app.RollDiceButton_2, 'Enable', 'off');
               set(app.EndTurnButton_2, 'Enable', 'off');
               set(app.RollDiceButton, 'Enable', 'on');
               set(app.EndTurnButton, 'Enable', 'on');
        end

        % Button pushed function: DesignateTurnOrderButton
        function DesignateTurnOrderButtonPushed(app, event)
            dice_roll_one = randi([1, 6]);
            dice_roll_two = randi([1, 6]);
            [y, Fs] = audioread(fullfile("Sounds","dice.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            pause(0.2);
            app.Image.ImageSource = fullfile("Images", "dice_rolling.gif");
            pause(1)
            switch dice_roll_one
                case 1
                    app.Image.ImageSource = fullfile("Images", "dice_1.png"); % rolled a 1
                case 2
                    app.Image.ImageSource = fullfile("Images", "dice_2.png"); % rolled a 2
                case 3
                    app.Image.ImageSource = fullfile("Images", "dice_3.png"); % rolled a 3
                case 4
                    app.Image.ImageSource = fullfile("Images", "dice_4.png"); % rolled a 4
                case 5
                    app.Image.ImageSource = fullfile("Images", "dice_5.png"); % rolled a 5
                case 6
                    app.Image.ImageSource = fullfile("Images", "dice_6.png"); % rolled a 6
            end
            switch dice_roll_two
                case 1
                    app.Image.ImageSource = fullfile("Images", "dice_1.png"); % rolled a 1
                case 2
                    app.Image.ImageSource = fullfile("Images", "dice_2.png"); % rolled a 2
                case 3
                    app.Image.ImageSource = fullfile("Images", "dice_3.png"); % rolled a 3
                case 4
                    app.Image.ImageSource = fullfile("Images", "dice_4.png"); % rolled a 4
                case 5
                    app.Image.ImageSource = fullfile("Images", "dice_5.png"); % rolled a 5
                case 6
                    app.Image.ImageSource = fullfile("Images", "dice_6.png"); % rolled a 6
            end
            if dice_roll_one > dice_roll_two
                set(app.RollDiceButton, 'Enable', 'on');
                set(app.EndTurnButton, 'Enable', 'on');
                app.DesignateTurnOrderButton.Visible = 'off';
            elseif dice_roll_two > dice_roll_one
                set(app.RollDiceButton_2, 'Enable', 'on');
                set(app.EndTurnButton_2, 'Enable', 'on');
                app.DesignateTurnOrderButton.Visible = 'off';
            else 
                DesignateTurnOrderButtonPushed(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'copper');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1 Score'; 'Player 2 Score'};
            app.UITable.RowName = {};
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.EndTurnButton = uibutton(app.UIFigure, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.RollDiceButton_2 = uibutton(app.UIFigure, 'push');
            app.RollDiceButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButton_2Pushed, true);
            app.RollDiceButton_2.Text = 'Roll Dice';
            app.EndTurnButton_2 = uibutton(app.UIFigure, 'push');
            app.EndTurnButton_2.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButton_2Pushed, true);
            app.EndTurnButton_2.Text = 'End Turn';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.PigLabel = uilabel(app.UIFigure);
            app.PigLabel.Text = 'Pig';
            app.GameRulesButton = uibutton(app.UIFigure, 'push');
            app.GameRulesButton.ButtonPushedFcn = createCallbackFcn(app, @GameRulesButtonPushed, true);
            app.GameRulesButton.Text = 'Game Rules';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'dice_6.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'The game rules are simple:'; ''; '1. All the players are given one 6-sided dice which they take turns sharing. '; ''; '2. All the players then take turns rolling the dice and whoever gets the highest value starts first. If it is a tie, you repeat till the tie is broken. '; ''; '3. The starting player rolls the dice. The player can keep rolling the dice and try to score as many points as they want in their turn unless they roll a 1. If they roll a one, all the points accumulated in that turn is lost and it is now the next player''s turn'; ''; '4. To win the game, a player must reach 100 points first.'};
            app.Player1WinsLabel = uilabel(app.UIFigure);
            app.Player1WinsLabel.Text = 'Player 1 Wins ';
            app.Player1WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player1WinsEditField.Editable = 'off';
            app.Player2WinsEditFieldLabel = uilabel(app.UIFigure);
            app.Player2WinsEditFieldLabel.Text = 'Player 2 Wins ';
            app.Player2WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player2WinsEditField.Editable = 'off';
            app.DesignateTurnOrderButton = uibutton(app.UIFigure, 'push');
            app.DesignateTurnOrderButton.ButtonPushedFcn = createCallbackFcn(app, @DesignateTurnOrderButtonPushed, true);
            app.DesignateTurnOrderButton.Text = 'Designate Turn Order';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Pig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end