classdef multiplayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlayerDropDown             matlab.ui.control.DropDown
        PlayerDropDownLabel        matlab.ui.control.Label
        Player2WinsEditField       matlab.ui.control.NumericEditField
        Player2WinsEditFieldLabel  matlab.ui.control.Label
        Player1WinsEditField       matlab.ui.control.NumericEditField
        Player1WinsLabel           matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
        GameRulesButton            matlab.ui.control.Button
        PigLabel                   matlab.ui.control.Label
        Player2Label               matlab.ui.control.Label
        Player1Label               matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        my_turn
        other_turn
        player1_score
        player2_score
        start_score
        other_score
        channelID
        writeKey
        readKey
        readDelay
        writeDelay 
    end
    
    methods (Access = private)
        
        function [] = TableThingSpeak(app)
            channelID = 2770412;
            userAPIKey = 'P14ZGFR1WAHUOHJV';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
    webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'HMGLSOSM3V42CQMY';
            writeKey = 'YX10CCCUV92U7V14';
        end
        
       function [] = ClearThinkSpeakChannel(app)
            thingSpeakWrite(2770412, 'Fields', 3, 'Values', "0", 'WriteKey', app.writeKey);
       end

       function [] = WaitforTurn(app)
           turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
           pause(3)
           while app.my_turn ~= turn
               if app.my_turn == 2
                 set(app.RollDiceButton_2, 'Enable', 'off');
                 set(app.EndTurnButton_2, 'Enable', 'off');
                 app.UITable.Data(1) = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 1);
                 pause(5);
               else
                   set(app.RollDiceButton, 'Enable', 'off');
                    set(app.EndTurnButton, 'Enable', 'off');
                    app.UITable.Data(2) = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 2);
                    pause(5);
              end
           end
       end

       function [] = CheckForTurn(app)
           pause(3)
           turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
           disp("entered")
           if app.my_turn == 1
               if turn == app.my_turn
                   disp("worked for player 1")
                   set(app.RollDiceButton, 'Enable', 'on');
                    set(app.EndTurnButton, 'Enable', 'on');
               else
                   pause(1)
                   app.UITable.Data(2) = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 2);
                   disp("did not work for player 1")
                   CheckForTurn(app)
               end
           elseif app.my_turn == 2
               if turn == app.my_turn
                   disp("worked for player 2")
                   set(app.RollDiceButton_2, 'Enable', 'on');
                 set(app.EndTurnButton_2, 'Enable', 'on');
               else
                   pause(1)
                    app.UITable.Data(1) = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 1);
                    disp("did not work for player 2")
                   CheckForTurn(app)
                   pause(1)
               end
           end
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          
        end

        % Button pushed function: GameRulesButton
        function GameRulesButtonPushed(app, event)
           global i % called the global variable
           i = i+1; % adding an iteration to count how many times the button is pressed
           set(app.TextArea, 'Visible','on'); % makes the text area visible
           if (mod(i, 2) == 0) 
                set(app.TextArea, 'Visible', 'off') % if it is divisible by 2 (open and close), it closes
           else
               set(app.TextArea, 'Visible', 'on') % else, open it
           end
        end

        % Value changed function: PlayerDropDown
        function PlayerDropDownValueChanged(app, event)
            global channelID
            global writeKey
            value = app.PlayerDropDown.Value;
            % set(app.PlayerDropDown, 'Visible', 'off');
            % set(app.PlayerDropDownLabel, 'Visible', 'off');
            if value == "Player 1"
                delete(app)
                player1
            else
               delete(app)
               player2
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'copper');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1 Score'; 'Player 2 Score'};
            app.UITable.RowName = {};
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.PigLabel = uilabel(app.UIFigure);
            app.PigLabel.Text = 'Pig';
            app.GameRulesButton = uibutton(app.UIFigure, 'push');
            app.GameRulesButton.ButtonPushedFcn = createCallbackFcn(app, @GameRulesButtonPushed, true);
            app.GameRulesButton.Text = 'Game Rules';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'dice_6.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'The game rules are simple:'; ''; '1. All the players are given one 6-sided dice which they take turns sharing. '; ''; '2. The starting player rolls the dice. The player can keep rolling the dice and try to score as many points as they want in their turn unless they roll a 1. If they roll a one, all the points accumulated in that turn is lost and it is now the next player''s turn'; ''; '3. To win the game, a player must reach 100 points first.'};
            app.Player1WinsLabel = uilabel(app.UIFigure);
            app.Player1WinsLabel.Text = 'Player 1 Wins ';
            app.Player1WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player1WinsEditField.Editable = 'off';
            app.Player2WinsEditFieldLabel = uilabel(app.UIFigure);
            app.Player2WinsEditFieldLabel.Text = 'Player 2 Wins ';
            app.Player2WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player2WinsEditField.Editable = 'off';
            app.PlayerDropDownLabel = uilabel(app.UIFigure);
            app.PlayerDropDownLabel.Text = 'Player #';
            app.PlayerDropDown = uidropdown(app.UIFigure);
            app.PlayerDropDown.Items = {'Select', 'Player 1', 'Player 2'};
            app.PlayerDropDown.ValueChangedFcn = createCallbackFcn(app, @PlayerDropDownValueChanged, true);
            app.PlayerDropDown.Placeholder = 'Selection';
            app.PlayerDropDown.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiplayer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end