classdef player1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Player2WinsEditField       matlab.ui.control.NumericEditField
        Player2WinsEditFieldLabel  matlab.ui.control.Label
        Player1WinsEditField       matlab.ui.control.NumericEditField
        Player1WinsLabel           matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
        GameRulesButton            matlab.ui.control.Button
        PigLabel                   matlab.ui.control.Label
        Player1Label               matlab.ui.control.Label
        EndTurnButton              matlab.ui.control.Button
        RollDiceButton             matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        my_turn
        other_turn
        player1_score
        start_score
        other_score
        channelID
        writeKey
        readKey
        readDelay
        writeDelay
        other_wins
    end
    
    methods (Access = private)
        
        function [] = TableThingSpeak(app)
            channelID = 2770412;
            userAPIKey = 'P14ZGFR1WAHUOHJV';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
    webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'HMGLSOSM3V42CQMY';
            writeKey = 'YX10CCCUV92U7V14';
        end
        
       function [] = ClearThinkSpeakChannel(app)
            thingSpeakWrite(2770412, 'Fields', 3, 'Values', "0", 'WriteKey', app.writeKey);
       end

       function [] = CheckForTurn(app)
           global score1
           global change1
           global channelID
           global readKey
           increment = 1;
           pause(1)
           app.other_wins = thingSpeakRead(channelID, 'ReadKey', readKey, 'Fields', 5);
           if ~isnan(app.other_wins)
               app.Player2WinsEditField.Value = app.other_wins;
           end
           pause(2)
           turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
           if app.other_wins >= increment
               score1 = 0;
               change1 = 0;
               increment = increment+1;
           end
           if turn == app.my_turn
               set(app.RollDiceButton, 'Enable', 'on');
               set(app.EndTurnButton, 'Enable', 'on');
           else
               pause(1)
               app.other_score = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 2);
               app.UITable.Data(2) = app.other_score;
               CheckForTurn(app)
           end
           
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           global i % set this up a global variable to use for open/close of game rules
           global score1
           global change1
           global channelID
           global userAPIKey
           global url
           global readKey
           global writeKey
           app.other_wins = 0;
           i = 0; % set the initial value of i
           change1 = 0;
           score1 = 0;
           channelID = 2770412;
            userAPIKey = 'P14ZGFR1WAHUOHJV';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
    webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'HMGLSOSM3V42CQMY';
            writeKey = 'YX10CCCUV92U7V14';
            set(app.RollDiceButton, 'Enable', 'on');
            set(app.EndTurnButton, 'Enable', 'on');
            app.my_turn = 1;
            pause(1)
            thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [3, 4, 5], 'Values', [1, 0, 0])
            pause(4)
            while app.my_turn ~= thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3)
                CheckForTurn(app)
                pause(3)
            end
        end

        % Button pushed function: RollDiceButton
        function RollDiceButtonPushed(app, event)
            global score1
            global change1
            global channelID
            global userAPIKey
            global url
            global readKey
            global writeKey
            [y, Fs] = audioread(fullfile("Sounds", "dice.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            pause(0.2);
            app.Image.ImageSource = fullfile("Images", "dice_rolling.gif"); % substitutes the image with rolling dice gif
           pause(1); % rolling dice gif
            dice_roll = randi([1, 6]); % rolls a random number from 1 to 6
            switch dice_roll
                case 1
                    app.Image.ImageSource = fullfile("Images", "dice_1.png"); % rolled a 1
                case 2
                    app.Image.ImageSource = fullfile("Images", "dice_2.png"); % rolled a 2
                case 3
                    app.Image.ImageSource = fullfile("Images", "dice_3.png"); % rolled a 3
                case 4
                    app.Image.ImageSource = fullfile("Images", "dice_4.png"); % rolled a 4
                case 5
                    app.Image.ImageSource = fullfile("Images", "dice_5.png"); % rolled a 5
                case 6
                    app.Image.ImageSource = fullfile("Images", "dice_6.png"); % rolled a 6
            end

            change1 = change1 + dice_roll;
           if dice_roll == 1
               score1 = score1 - change1 + 1;
               thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [1, 3], 'Values', [score1, 2])
               app.UITable.Data(1) = thingSpeakRead(channelID, 'ReadKey', readKey, 'Fields', 1);
               set(app.RollDiceButton, 'Enable', 'off');
               set(app.EndTurnButton, 'Enable', 'off');
               change1 = 0;
               CheckForTurn(app)
           else
               score1 = score1 + dice_roll;
               app.UITable.Data(1) = sum(score1);
               if score1 >= 100
                msgbox('Player 1 has won')
                app.Player1WinsEditField.Value = app.Player1WinsEditField.Value + 1;
                score1 = 0;
                change1 = 0;
                app.UITable.Data(1) = sum(score1);
                app.UITable.Data(2) = 0;
                thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', 4, 'Values', app.Player1WinsEditField.Value)
                EndTurnButtonPushed(app)
                pause(1)
               end
           end
        end

        % Button pushed function: GameRulesButton
        function GameRulesButtonPushed(app, event)
           global i % called the global variable
           i = i+1; % adding an iteration to count how many times the button is pressed
           set(app.TextArea, 'Visible','on'); % makes the text area visible
           if (mod(i, 2) == 0) 
                set(app.TextArea, 'Visible', 'off') % if it is divisible by 2 (open and close), it closes
           else
               set(app.TextArea, 'Visible', 'on') % else, open it
           end
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            global change1
            global channelID
            global writeKey
            global readKey
            global score1
        
          %  app.Player2WinsEditField.Value = app.other_wins;
            [y, Fs] = audioread(fullfile("Sounds","chime.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            app.player1_score = score1;
             turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
             pause(1)
             thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [1, 3], 'Values', [score1, 2])
                change1 = 0;
               set(app.RollDiceButton, 'Enable', 'off');
               set(app.EndTurnButton, 'Enable', 'off');
              CheckForTurn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'copper');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1 Score'; 'Player 2 Score'};
            app.UITable.RowName = {};
            app.RollDiceButton = uibutton(app.UIFigure, 'push');
            app.RollDiceButton.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButtonPushed, true);
            app.RollDiceButton.Text = 'Roll Dice';
            app.EndTurnButton = uibutton(app.UIFigure, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.PigLabel = uilabel(app.UIFigure);
            app.PigLabel.Text = 'Pig';
            app.GameRulesButton = uibutton(app.UIFigure, 'push');
            app.GameRulesButton.ButtonPushedFcn = createCallbackFcn(app, @GameRulesButtonPushed, true);
            app.GameRulesButton.Text = 'Game Rules';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'dice_6.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'The game rules are simple:'; ''; '1. All the players are given one 6-sided dice which they take turns sharing. '; ''; '2. The starting player rolls the dice. The player can keep rolling the dice and try to score as many points as they want in their turn unless they roll a 1. If they roll a one, all the points accumulated in that turn is lost and it is now the next player''s turn'; ''; '3. To win the game, a player must reach 100 points first.'};
            app.Player1WinsLabel = uilabel(app.UIFigure);
            app.Player1WinsLabel.Text = 'Player 1 Wins ';
            app.Player1WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player1WinsEditField.Editable = 'off';
            app.Player2WinsEditFieldLabel = uilabel(app.UIFigure);
            app.Player2WinsEditFieldLabel.Text = 'Player 2 Wins ';
            app.Player2WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player2WinsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = player1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end