classdef player2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Player2WinsEditField       matlab.ui.control.NumericEditField
        Player2WinsEditFieldLabel  matlab.ui.control.Label
        Player1WinsEditField       matlab.ui.control.NumericEditField
        Player1WinsLabel           matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        Image                      matlab.ui.control.Image
        GameRulesButton            matlab.ui.control.Button
        PigLabel                   matlab.ui.control.Label
        Player2Label               matlab.ui.control.Label
        EndTurnButton_2            matlab.ui.control.Button
        RollDiceButton_2           matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        my_turn
        other_turn
        player1_score
        player2_score
        start_score
        other_score
        channelID
        writeKey
        readKey
        readDelay
        writeDelay
        other_wins
    end
    
    methods (Access = private)
        
        function [] = TableThingSpeak(app)
            channelID = 2770412;
            userAPIKey = 'P14ZGFR1WAHUOHJV';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
    webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'HMGLSOSM3V42CQMY';
            writeKey = 'YX10CCCUV92U7V14';
        end
        
       function [] = ClearThinkSpeakChannel(app)
            thingSpeakWrite(2770412, 'Fields', 3, 'Values', "0", 'WriteKey', app.writeKey);
       end


       function [] = CheckForTurn(app)
           global score2
           global change2
           global channelID
           global readKey
           increment = 1;
           pause(1)
           app.other_wins = thingSpeakRead(channelID, 'ReadKey', readKey, 'Fields', 4);
           if ~isnan(app.other_wins)
               app.Player1WinsEditField.Value = app.other_wins;
           end
           pause(2)
           
           turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
           
           if app.other_wins >= increment
               score2 = 0;
               change2 = 0;
               increment = increment+1;
           end
           turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
           if turn == app.my_turn
              disp('worked')
              set(app.RollDiceButton_2, 'Enable', 'on');
              set(app.EndTurnButton_2, 'Enable', 'on');
           else
               disp('not working')
               pause(1)
               app.other_score = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 1);
               app.UITable.Data(1) = app.other_score;
              
              CheckForTurn(app)
            end
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           global i % set this up a global variable to use for open/close of game rules
           global score2
           global change2
           global channelID
           global userAPIKey
           global url
           global readKey
           global writeKey
           
           i = 0; % set the initial value of i
           
           app.my_turn = 2;
           change2 = 0;
           score2 = 0;
           channelID = 2770412;
            userAPIKey = 'P14ZGFR1WAHUOHJV';
             url = sprintf('https://api.thingspeak.com/channels/%s/feeds.json?api_key=%s', num2str(channelID), userAPIKey);
            webwrite(url, weboptions('RequestMethod','delete'));
            readKey = 'HMGLSOSM3V42CQMY';
            writeKey = 'YX10CCCUV92U7V14';
            set(app.RollDiceButton_2, 'Enable', 'off');
            set(app.EndTurnButton_2, 'Enable', 'off');
            thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', 3, 'Values', 1)
            pause(2)
            while app.my_turn ~= thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3)
                disp('entering')
                CheckForTurn(app)
                pause(3)
            end
        end

        % Button pushed function: GameRulesButton
        function GameRulesButtonPushed(app, event)
           global i % called the global variable
           i = i+1; % adding an iteration to count how many times the button is pressed
           set(app.TextArea, 'Visible','on'); % makes the text area visible
           if (mod(i, 2) == 0) 
                set(app.TextArea, 'Visible', 'off') % if it is divisible by 2 (open and close), it closes
           else
               set(app.TextArea, 'Visible', 'on') % else, open it
           end
        end

        % Button pushed function: RollDiceButton_2
        function RollDiceButton_2Pushed(app, event)
            global score2
            global change2
            global channelID
            global writeKey
            global readKey
            
            %set(app.RollDiceButton, 'Enable', 'off');
            %set(app.EndTurnButton, 'Enable', 'off');
            %currentWins = double(thingSpeakRead(channelID, 'ReadKey', readKey, 'Fields', 4));
            %if (double(currentWins) - other_wins) > 0
            %    score2 = 0;
            %    change2 = 0;
            %   app.Player1WinsEditField.Value = app.Player1WinsEditField.Value + 1;
            %end
            [y, Fs] = audioread(fullfile("Sounds", "dice.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            pause(0.2);
            app.Image.ImageSource = fullfile("Images", "dice_rolling.gif"); % substitutes the image with rolling dice gif
           pause(1); % rolling dice gif
            dice_roll = randi([1, 6]); % rolls a random number from 1 to 6
            switch dice_roll
                case 1
                    app.Image.ImageSource = fullfile("Images", "dice_1.png"); % rolled a 1
                case 2
                    app.Image.ImageSource = fullfile("Images", "dice_2.png"); % rolled a 2
                case 3
                    app.Image.ImageSource = fullfile("Images", "dice_3.png"); % rolled a 3
                case 4
                    app.Image.ImageSource = fullfile("Images", "dice_4.png"); % rolled a 4
                case 5
                    app.Image.ImageSource = fullfile("Images", "dice_5.png"); % rolled a 5
                case 6
                    app.Image.ImageSource = fullfile("Images", "dice_6.png"); % rolled a 6
            end

            change2 = change2 + dice_roll;
            if dice_roll == 1
               score2 = score2 - change2 + 1;
               thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [2, 3], 'Values', [score2, 1])
               app.UITable.Data(2) = thingSpeakRead(channelID, 'ReadKey', readKey, 'Fields', 2);
               set(app.RollDiceButton_2, 'Enable', 'off');
               set(app.EndTurnButton_2, 'Enable', 'off');
               change2 = 0;
               CheckForTurn(app)
               % thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', 3, 'Values', 1)
            else
                score2 = score2 + dice_roll;
                app.UITable.Data(2) = score2;
                if score2 >= 10
                msgbox('Player 2 has won')
                app.Player2WinsEditField.Value = app.Player2WinsEditField.Value + 1;
                score2 = 0;
                change2 = 0;
                app.UITable.Data(2) = 0;
                app.UITable.Data(1) = 0;

                thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', 5, 'Values', app.Player2WinsEditField.Value);
                pause(1)
                end
                
            end
            
        end

        % Button pushed function: EndTurnButton_2
        function EndTurnButton_2Pushed(app, event)
            global change2
            global channelID
            global writeKey
            global score2
            
            [y, Fs] = audioread(fullfile("Sounds","chime.mp3")); % reads the audio file
            sound(y, Fs); % plays the sound
            app.player2_score = score2;
            turn = thingSpeakRead(2770412, 'ReadKey', 'HMGLSOSM3V42CQMY', 'Fields', 3);
            pause(1)
            thingSpeakWrite(channelID, 'WriteKey', writeKey, 'Fields', [2, 3], 'Values', [score2, 1])
               change2 = 0;
               set(app.RollDiceButton_2, 'Enable', 'off');
               set(app.EndTurnButton_2, 'Enable', 'off');
               CheckForTurn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'copper');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Player 1 Score'; 'Player 2 Score'};
            app.UITable.RowName = {};
            app.RollDiceButton_2 = uibutton(app.UIFigure, 'push');
            app.RollDiceButton_2.ButtonPushedFcn = createCallbackFcn(app, @RollDiceButton_2Pushed, true);
            app.RollDiceButton_2.Text = 'Roll Dice';
            app.EndTurnButton_2 = uibutton(app.UIFigure, 'push');
            app.EndTurnButton_2.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButton_2Pushed, true);
            app.EndTurnButton_2.Text = 'End Turn';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.PigLabel = uilabel(app.UIFigure);
            app.PigLabel.Text = 'Pig';
            app.GameRulesButton = uibutton(app.UIFigure, 'push');
            app.GameRulesButton.ButtonPushedFcn = createCallbackFcn(app, @GameRulesButtonPushed, true);
            app.GameRulesButton.Text = 'Game Rules';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'dice_6.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'The game rules are simple:'; ''; '1. All the players are given one 6-sided dice which they take turns sharing. '; ''; '2. The starting player rolls the dice. The player can keep rolling the dice and try to score as many points as they want in their turn unless they roll a 1. If they roll a one, all the points accumulated in that turn is lost and it is now the next player''s turn'; ''; '3. To win the game, a player must reach 100 points first.'};
            app.Player1WinsLabel = uilabel(app.UIFigure);
            app.Player1WinsLabel.Text = 'Player 1 Wins ';
            app.Player1WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player1WinsEditField.Editable = 'off';
            app.Player2WinsEditFieldLabel = uilabel(app.UIFigure);
            app.Player2WinsEditFieldLabel.Text = 'Player 2 Wins ';
            app.Player2WinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Player2WinsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = player2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end